classdef ksim < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        ImportMenu                    matlab.ui.container.Menu
        ExportMenu                    matlab.ui.container.Menu
        GridLayout                    matlab.ui.container.GridLayout
        LeftPanel                     matlab.ui.container.Panel
        GridLayout4                   matlab.ui.container.GridLayout
        ViewLabel                     matlab.ui.control.Label
        SliceViewDropDown             matlab.ui.control.DropDown
        SliceLabel                    matlab.ui.control.Label
        SliceSpinner                  matlab.ui.control.Spinner
        TabGroup                      matlab.ui.container.TabGroup
        MRParamsTab                   matlab.ui.container.Tab
        GridLayout3                   matlab.ui.container.GridLayout
        NumberofCoilsSpinner          matlab.ui.control.Spinner
        NumberofCoilsSpinnerLabel     matlab.ui.control.Label
        FlipAngledegEditFieldLabel    matlab.ui.control.Label
        FlipAngledegEditField         matlab.ui.control.NumericEditField
        RepetitionTimeTRmsEditField   matlab.ui.control.NumericEditField
        RepetitionTimeTRmsEditFieldLabel  matlab.ui.control.Label
        EchoTimeTEmsEditFieldLabel    matlab.ui.control.Label
        EchoTimeTEmsEditField         matlab.ui.control.NumericEditField
        FieldStrengthTEditFieldLabel  matlab.ui.control.Label
        FieldStrengthTEditField       matlab.ui.control.NumericEditField
        AccelerationFactorLabel       matlab.ui.control.Label
        AccelerationFactorSpinner     matlab.ui.control.Spinner
        TrajectoryDropDownLabel       matlab.ui.control.Label
        TrajectoryDropDown            matlab.ui.control.DropDown
        ViewLabel_2                   matlab.ui.control.Label
        SignalEquationDropdown        matlab.ui.control.DropDown
        fMRIParamsTab                 matlab.ui.container.Tab
        GridLayout2                   matlab.ui.container.GridLayout
        CNREditField                  matlab.ui.control.NumericEditField
        CNREditFieldLabel             matlab.ui.control.Label
        ActivationPhase               matlab.ui.control.NumericEditField
        PhaseIncreaseLabel            matlab.ui.control.Label
        ActivationMag                 matlab.ui.control.NumericEditField
        ActivationLabel               matlab.ui.control.Label
        TaskSIgnalIncreaseLabel       matlab.ui.control.Label
        PlotActivationCheck           matlab.ui.control.CheckBox
        MapViewSelect                 matlab.ui.container.ButtonGroup
        viewM0                        matlab.ui.control.RadioButton
        viewT1                        matlab.ui.control.RadioButton
        viewT2                        matlab.ui.control.RadioButton
        viewdB                        matlab.ui.control.RadioButton
        FileSelectorandViewerLabel    matlab.ui.control.Label
        LoadM0FileButton              matlab.ui.control.Button
        M0FileNameEditField           matlab.ui.control.EditField
        LoadT1FileButton              matlab.ui.control.Button
        T1FileNameEditField           matlab.ui.control.EditField
        LoadT2FileButton              matlab.ui.control.Button
        T2FileNameEditField           matlab.ui.control.EditField
        LoaddBFileButton              matlab.ui.control.Button
        dBFileNameEditField           matlab.ui.control.EditField
        LoadActFileButton             matlab.ui.control.Button
        ActFileNameEditField          matlab.ui.control.EditField
        MapAxes                       matlab.ui.control.UIAxes
        CenterPanel                   matlab.ui.container.Panel
        GridLayout5                   matlab.ui.container.GridLayout
        ActivationLabel_2             matlab.ui.control.Label
        ActivationLamp                matlab.ui.control.Lamp
        CoilNumberSpinner             matlab.ui.control.Spinner
        CoilNumberSpinnerLabel        matlab.ui.control.Label
        DomainDropDown                matlab.ui.control.DropDown
        DomainDropDownLabel           matlab.ui.control.Label
        TimePointSpinner              matlab.ui.control.Spinner
        TimePointSpinnerLabel         matlab.ui.control.Label
        kSpaceButton                  matlab.ui.control.Button
        VoxelDataDropDown             matlab.ui.control.DropDown
        VoxelDataDropDownLabel        matlab.ui.control.Label
        TaskDesignPanel               matlab.ui.container.Panel
        GridLayout6                   matlab.ui.container.GridLayout
        GenerateTimeSeriesDataButton  matlab.ui.control.Button
        PlotDesignButton              matlab.ui.control.Button
        RestImagesEditField           matlab.ui.control.NumericEditField
        RestImagesEditFieldLabel      matlab.ui.control.Label
        TaskImagesEditField           matlab.ui.control.NumericEditField
        TaskImagesEditFieldLabel      matlab.ui.control.Label
        EpochsEditField               matlab.ui.control.NumericEditField
        EpochsEditFieldLabel          matlab.ui.control.Label
        HRFDropDown                   matlab.ui.control.DropDown
        HRFDropDownLabel              matlab.ui.control.Label
        timeSeriesAxes                matlab.ui.control.UIAxes
        reconAxes_2                   matlab.ui.control.UIAxes
        reconAxes                     matlab.ui.control.UIAxes
        kSpaceAxes_2                  matlab.ui.control.UIAxes
        kSpaceAxes                    matlab.ui.control.UIAxes
        RightPanel                    matlab.ui.container.Panel
        GridLayout7                   matlab.ui.container.GridLayout
        TextOutput                    matlab.ui.control.TextArea
        ExportSinglekSpaceButton      matlab.ui.control.StateButton
        ExportTimeSeriesButton        matlab.ui.control.Button
        kSpaceSimulationToolMarquetteUniversityLabel  matlab.ui.control.Label
        Hyperlink                     matlab.ui.control.Hyperlink
        reconstructButton             matlab.ui.control.Button
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
        twoPanelWidth = 768;
    end

    
    properties (Access = public)
        M0Map; T1Map; T2Map;
        dBMap; ActMap; MASK;
        mapView; mapViewInputData;
    
        B0; TE; TR;

        rec_size; kx; ky;
        kSpaceRest; kSpaceTask;


        %fmri stuff
        design; sigma;
        kSpaceTimeSeries;
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            %%%%% ADD PATHS %%%%%
            appPath = fileparts(mfilename('fullpath'));
            addpath(appPath,'functions\');
            
            %%%%% LOAD INITIAL MAPS %%%%%
            M0FileName = fullfile(appPath,'data/M0Map96.mat');
            app.M0Map = load(M0FileName,'-mat');
            T1FileName = fullfile(appPath,'data/T1Map96.mat');
            app.T1Map = load(T1FileName);
            T2FileName = fullfile(appPath,'data/T2Map96.mat');
            app.T2Map = load(T2FileName);
            ActFileName = fullfile(appPath,'data/ActMap96.mat');
            app.ActMap = load(ActFileName);

            %%%%% INITIALIZE VALUES %%%%%
            app.M0FileNameEditField.Value = 'M0Map96.mat';
            app.T1FileNameEditField.Value = 'T1Map96.mat';
            app.T2FileNameEditField.Value = 'T2Map96.mat';
            app.ActFileNameEditField.Value = 'ActMap96.mat';
            app.TE = app.EchoTimeTEmsEditField.Value;
            app.TR = app.RepetitionTimeTRmsEditField.Value;
            app.rec_size = length(app.M0Map.M0Map);


            %%%%% INITIALIZE MAP VIEWER %%%%%
            app.mapViewInputData = app.M0Map.M0Map;
            app.UpdateMapsPlot()
            %app.kSpaceButtonPushed() %slows startup too much, not necessary


        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.UIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 3x1 grid
                app.GridLayout.RowHeight = {835, 835, 835};
                app.GridLayout.ColumnWidth = {'1x'};
                app.CenterPanel.Layout.Row = 1;
                app.CenterPanel.Layout.Column = 1;
                app.LeftPanel.Layout.Row = 2;
                app.LeftPanel.Layout.Column = 1;
                app.RightPanel.Layout.Row = 3;
                app.RightPanel.Layout.Column = 1;
            elseif (currentFigureWidth > app.onePanelWidth && currentFigureWidth <= app.twoPanelWidth)
                % Change to a 2x2 grid
                app.GridLayout.RowHeight = {835, 835};
                app.GridLayout.ColumnWidth = {'1x', '1x'};
                app.CenterPanel.Layout.Row = 1;
                app.CenterPanel.Layout.Column = [1,2];
                app.LeftPanel.Layout.Row = 2;
                app.LeftPanel.Layout.Column = 1;
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 2;
            else
                % Change to a 1x3 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {334, '1x', 287};
                app.LeftPanel.Layout.Row = 1;
                app.LeftPanel.Layout.Column = 1;
                app.CenterPanel.Layout.Row = 1;
                app.CenterPanel.Layout.Column = 2;
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 3;
            end
        end

        % Button pushed function: LoadM0FileButton
        function LoadM0FileButtonPushed(app, event)
            [filename, path] = uigetfile();
            if ~isequal(filename,0)
                app.M0FileNameEditField.Value=filename;
                app.M0Map = load(fullfile(path,filename));            
                app.MapViewSelectSelectionChanged(app);
                app.rec_size = length(app.M0Map.M0Map);
                app.SliceSpinner.Limits = [1,app.rec_size];
                %app.UpdateMapsPlot(app,event);
            end
        end

        % Button pushed function: LoadT1FileButton
        function LoadT1FileButtonPushed(app, event)
            [filename, path] = uigetfile();
            if ~isequal(filename,0)
                app.T1FileNameEditField.Value=filename;
                app.T1Map = load(fullfile(path,filename));         
                app.MapViewSelectSelectionChanged(app);
                %app.UpdateMapsPlot(app,event);
                %figure(app.UIFigure);
            end
        end

        % Button pushed function: LoadT2FileButton
        function LoadT2FileButtonPushed(app, event)
            [filename, path] = uigetfile();
            if ~isequal(filename,0)
                app.T2FileNameEditField.Value=filename;
                app.T2Map = load(fullfile(path,filename));
                app.MapViewSelectSelectionChanged(app);
                %app.UpdateMapsPlot(app,event);
                %figure(app.UIFigure);
            end
        end

        % Button pushed function: LoaddBFileButton
        function LoaddBFileButtonPushed(app, event)
            [filename, path] = uigetfile();
            if ~isequal(filename,0)
                app.dBFileNameEditField.Value=filename;
                app.dBMap = load(fullfile(path,filename));
                app.MapViewSelectSelectionChanged(app);
                %app.UpdateMapsPlot(app,event)
                %figure(app.UIFigure);
            end
        end

        % Button pushed function: LoadActFileButton
        function LoadActFileButtonPushed(app, event)
            [filename, path] = uigetfile();
            if ~isequal(filename,0)
                app.ActFileNameEditField.Value=filename;
                app.ActMap = load(fullfile(path,filename));
                app.MapViewSelectSelectionChanged(app);
                %app.UpdateMapsPlot(app,event)
                %figure(app.UIFigure);
            end
        end

        % Value changed function: PlotActivationCheck
        function PlotActivationCheckValueChanged(app, event)
            UpdateMapsPlot(app,event)
            value = app.PlotActivationCheck.Value;
            %app.TextOutput.Value = strjoin({'Activation Value is:',char(value+'0')});
%             if value == 0
%                 %app.ActivationMag.Value = 0;
%                 %app.ActivationPhase.Value = 0;
%                 app.ActivationMag.Editable = 'off';
%                 app.ActivationPhase.Editable = 'off';
%             elseif value == 1
%                 app.ActivationMag.Editable = 'on';
%                 app.ActivationPhase.Editable = 'on';
%             end
        end

        % Selection changed function: MapViewSelect
        function MapViewSelectSelectionChanged(app, event)
            %selectedButton = app.MapViewSelect.SelectedObject;
            %disp(selectedButton)
            if app.viewM0.Value == 1
                app.mapViewInputData = app.M0Map.M0Map;
            elseif app.viewT1.Value == 1
                app.mapViewInputData = app.T1Map.T1Map;
            elseif app.viewT2.Value == 1
                app.mapViewInputData = app.T2Map.T2Map;
            end
            UpdateMapsPlot(app,event)
        end

        % Value changed function: SliceSpinner
        function SliceSpinnerValueChanged(app, event)
            %value = app.SliceSpinner.Value;
            UpdateMapsPlot(app,event)
        end

        % Value changed function: SliceViewDropDown
        function SliceViewDropDownValueChanged(app, event)
            %value = app.SliceViewDropDown.Value;
            UpdateMapsPlot(app,event)
        end

        % Callback function
        function UpdateMapsPlot(app, event)
            sliceN = app.SliceSpinner.Value;
            sliceView = str2double(app.SliceViewDropDown.Value);
            data = app.mapViewInputData;

            if sliceView == 1 % axial
                data = squeeze(data(:,:,sliceN));
            elseif sliceView == 2 % sagital
                data = rot90(squeeze(data(:,sliceN,:)));
            elseif sliceView == 3 % coronal
                data = rot90(squeeze(data(sliceN,:,:)));
            end
            %imagesc(app.MapAxes,data);

            %slightly redundant with MapViewSelectSelectionChanged
            if app.viewM0.Value == 1
                imagesc(app.MapAxes,data,[0 1]);
                colormap(app.MapAxes,'gray');
            elseif app.viewT1.Value == 1
                imagesc(app.MapAxes,data,[0 4]);
                colormap(app.MapAxes,'jet')
            elseif app.viewT2.Value == 1
                imagesc(app.MapAxes,data,[0 3]);
                colormap(app.MapAxes,'jet')
            end

            if app.PlotActivationCheck.Value == 1
                hold(app.MapAxes, 'on')
                imagesc(app.MapAxes,app.ActMap.ActMap,'AlphaData',0.75)
                hold(app.MapAxes, 'off')
            end

            
            colorbar(app.MapAxes);
        end

        % Value changed function: SignalEquationDropdown
        function SignalEquationDropdownValueChanged(app, event)
            value = str2double(app.SignalEquationDropdown.Value);
            if (value == 2 || value == 3)
                app.FlipAngledegEditField.Value = 90;
                app.FlipAngledegEditField.Editable = 'off';
            else
                app.FlipAngledegEditField.Editable = 'on';
            end
        end

        % Value changed function: FieldStrengthTEditField
        function FieldStrengthTEditFieldValueChanged(app, event)
            app.B0 = app.FieldStrengthTEditField.Value;
        end

        % Value changed function: EchoTimeTEmsEditField, HRFDropDown
        function EchoTimeTEmsEditFieldValueChanged(app, event)
            app.TE = app.EchoTimeTEmsEditField.Value;
        end

        % Value changed function: RepetitionTimeTRmsEditField
        function RepetitionTimeTRmsEditFieldValueChanged(app, event)
            app.TR = app.RepetitionTimeTRmsEditField.Value;
        end

        % Button pushed function: kSpaceButton
        function kSpaceButtonPushed(app, event)
            sliceN = app.SliceSpinner.Value;
            sliceView = str2double(app.SliceViewDropDown.Value);
            M0Map = app.M0Map.M0Map;
            T1Map = app.T1Map.T1Map;
            T2Map = app.T2Map.T2Map;

            app.rec_size = length(app.M0Map.M0Map);
            disp(app.rec_size)
            TE=app.EchoTimeTEmsEditField.Value/1000; % convert ms to s
            TR=app.TR/1000; % convert ms to s
            nA = app.AccelerationFactorSpinner.Value;

            if sliceView == 1 % axial
                M0Map = squeeze(M0Map(:,:,sliceN));
                T1Map = squeeze(T1Map(:,:,sliceN));
                T2Map = squeeze(T2Map(:,:,sliceN));
            elseif sliceView == 2 % sagital
                M0Map = rot90(squeeze(M0Map(:,sliceN,:)));
                T1Map = rot90(squeeze(T1Map(:,sliceN,:)));
                T2Map = rot90(squeeze(T2Map(:,sliceN,:)));
            elseif sliceView == 3 % coronal
                M0Map = rot90(squeeze(M0Map(sliceN,:,:)));
                T1Map = rot90(squeeze(T1Map(sliceN,:,:)));
                T2Map = rot90(squeeze(T2Map(sliceN,:,:)));
            end


            app.MASK = (M0Map./max(M0Map,[],'all')) > (1/10);

            trajectory = app.TrajectoryDropDown.Value;
            if trajectory == 1
                [app.kx,app.ky] = meshgrid(-app.rec_size/2:app.rec_size/2-1,-app.rec_size/2:app.rec_size/2-1);
                app.kx = app.kx(1:nA:end,:);
                app.ky = app.ky(1:nA:end,:);
                nkx = width(app.kx); nky = height(app.kx);
                app.kSpaceAxes.DataAspectRatio = [1 app.AccelerationFactorSpinner.Value 1];
                app.kSpaceAxes_2.DataAspectRatio = [1 app.AccelerationFactorSpinner.Value 1];
            elseif trajectory == 2
                nspokes = ceil(pi/2 * app.rec_size /nA); % pi/2 * sampling density
                nsamp = app.rec_size;
                xr=[-nsamp/2:1:nsamp/2-1]';
                angles = linspace(0,180,nspokes+1);
                angles(end)=[];
                app.kx=zeros(nsamp,nspokes);
                app.ky=zeros(nsamp,nspokes);
                for m=1:nspokes
                    app.kx(:,m) = xr*cosd(angles(m));
                    app.ky(:,m) = xr*sind(angles(m));
                end
                % include filter option later on... 12/05/2023
                % rampdcf = 1/nspokes + (app.kx.^2 + app.ky.^2).^(0.5);
                nkx = nspokes; nky = nsamp;
                app.kSpaceAxes.DataAspectRatio = [app.AccelerationFactorSpinner.Value 1 1];
                app.kSpaceAxes_2.DataAspectRatio = [app.AccelerationFactorSpinner.Value 1 1];
            end


            %app.sigma=0.05*max(max(abs(M0Map))); %no noise if not fmri, change later... 2/29/24
            %M0MapTask=[M0Map]; % so if no task, kspace task is just same as kspace rest
            %if app.PlotActivationCheck.Value == 1
            beta1 = app.ActivationMag.Value*max(max(abs(M0Map)));
            M0MapTask = (M0Map + app.ActMap.ActMap*beta1).*exp(sqrt(-1)*app.ActMap.ActMap*(pi/180*app.ActivationPhase.Value));
            app.sigma = 1/sqrt(nkx*nky*(beta1/app.CNREditField.Value)^2);
            %end


            signalEquationN = str2double(app.SignalEquationDropdown.Value);
            if signalEquationN == 1 % gradient echo
                app.kSpaceRest = kspace_GE(M0Map,T1Map,T2Map,-1,TE,TR,app.FlipAngledegEditField.Value,app.kx,app.ky);
                app.kSpaceTask = kspace_GE(M0MapTask,T1Map,T2Map,-1,TE,TR,app.FlipAngledegEditField.Value,app.kx,app.ky);
            elseif signalEquationN == 2 % spin echo
                app.kSpaceRest = kspace_SE(M0Map,T1Map,T2Map,-1,TE,TR,app.FlipAngledegEditField.Value,app.kx,app.ky);
                app.kSpaceTask = kspace_SE(M0MapTask,T1Map,T2Map,-1,TE,TR,app.FlipAngledegEditField.Value,app.kx,app.ky);
            elseif signalEquationN == 3 % inversion recovery
                app.kSpaceRest = kspace_IR(M0Map,T1Map,T2Map,-1,TE,TR,app.FlipAngledegEditField.Value,app.kx,app.ky);
                app.kSpaceTask = kspace_IR(M0MapTask,T1Map,T2Map,-1,TE,TR,app.FlipAngledegEditField.Value,app.kx,app.ky);
            end
            %app.kSpaceRest = app.kSpaceRest + app.sigma*randn(nky,nkx) + app.sigma*randn(nky,nkx)*sqrt(-1);

            if app.PlotActivationCheck.Value == 0
                imagesc(app.kSpaceAxes,log10(abs(app.kSpaceRest)+1))
                imagesc(app.kSpaceAxes_2,angle(app.kSpaceRest))
            elseif app.PlotActivationCheck.Value == 1
                imagesc(app.kSpaceAxes,log10(abs(app.kSpaceTask)+1))
                imagesc(app.kSpaceAxes_2,angle(app.kSpaceTask))
            end
            app.TextOutput.Value = strjoin(['Generated kSpace at',string(datetime('now','TimeZone','local','Format','d-MMM-y HH:mm:ss'))]);
     
            if trajectory == 1
                app.reconstructButtonPushed()
                app.TextOutput.Value = strjoin(['Reconstructed Images at',string(datetime('now','TimeZone','local','Format','d-MMM-y HH:mm:ss'))]);
            else
                % add this 12/05/2023 vvvvv 
                % clear axes
            end

            
        end

        % Button pushed function: reconstructButton
        function reconstructButtonPushed(app, event)
            app.reconAxes.DataAspectRatio = [1 app.AccelerationFactorSpinner.Value 1];
            app.reconAxes_2.DataAspectRatio = [1 app.AccelerationFactorSpinner.Value 1];

            if app.PlotActivationCheck.Value == 0
                magImage = abs(ifft2(app.kSpaceRest));
                phaseImage = angle(ifft2(fftshift(app.kSpaceRest)));
            elseif app.PlotActivationCheck.Value == 1
                magImage = abs(ifft2(app.kSpaceTask));
                phaseImage = angle(ifft2(fftshift(app.kSpaceTask)));
            end
            imagesc(app.reconAxes,magImage)
            imagesc(app.reconAxes_2,phaseImage)

            %SNR = globalSNR(magImage,app.MASK);
            %disp(SNR)
        end

        % Button pushed function: PlotDesignButton
        function PlotDesignButtonPushed(app, event)
            epochs = app.EpochsEditField.Value;
            restPerEpoch = app.RestImagesEditField.Value;
            taskPerEpoch = app.TaskImagesEditField.Value;
            app.design = repmat([zeros(restPerEpoch,1);ones(taskPerEpoch,1)],epochs,1);

            fTaskDesign = figure('Name','Task Design');
            plot(app.design,'black')
        end

        % Button pushed function: GenerateTimeSeriesDataButton
        function GenerateTimeSeriesDataButtonPushed(app, event)
            %%%%% REDUNDANT WITH POLOT DESIGN PUSYHED... 3/1/24 %%%%
            epochs = app.EpochsEditField.Value;
            restPerEpoch = app.RestImagesEditField.Value;
            taskPerEpoch = app.TaskImagesEditField.Value;
            app.design = repmat([zeros(restPerEpoch,1);ones(taskPerEpoch,1)],epochs,1);
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
                
            app.kSpaceButtonPushed()
            app.kSpaceTimeSeries = generateTimeSeries(app.kSpaceRest,app.kSpaceTask,app.EpochsEditField.Value,app.RestImagesEditField.Value,app.TaskImagesEditField.Value,app.sigma);
            app.TimePointSpinner.Limits=[1,size(app.kSpaceTimeSeries,3)];
            timeSeriesAxesButtonDown(app,event)
            app.TimePointSpinner.Enable = 'on';
            app.TextOutput.Value = strjoin(['Generated time series data at:',string(datetime('now','TimeZone','local','Format','d-MMM-y HH:mm:ss'))]);
        end

        % Value changed function: ExportSinglekSpaceButton
        function ExportSinglekSpaceButtonValueChanged(app, event)
            ks = app.kSpace;
            uisave('ks')
        end

        % Callback function: DomainDropDown, DomainDropDown,
        % DomainDropDown, 
        % ...and 3 other components
        function timeSeriesAxesButtonDown(app, event)
            voxelData = app.VoxelDataDropDown.Value;
            domain = app.DomainDropDown.Value;

            if app.design(app.TimePointSpinner.Value) == 1
                app.ActivationLamp.Color = [0,1,0];
            else
                app.ActivationLamp.Color = [1,0,0];
            end


            if domain == 1 % k space
                if voxelData == 1 % magnitude
                    imagesc(app.timeSeriesAxes,log10(abs(app.kSpaceTimeSeries(:,:,app.TimePointSpinner.Value))+1))
                elseif voxelData == 2 % phase
                    imagesc(app.timeSeriesAxes,angle(app.kSpaceTimeSeries(:,:,app.TimePointSpinner.Value)))
                elseif voxelData == 3 % real
                    imagesc(app.timeSeriesAxes,real(app.kSpaceTimeSeries(:,:,app.TimePointSpinner.Value)))
                elseif voxelData == 4 % imaginary
                    imagesc(app.timeSeriesAxes,imag(app.kSpaceTimeSeries(:,:,app.TimePointSpinner.Value)))
                end
            elseif domain == 2 % image space
                if voxelData == 1 % magnitude
                    imagesc(app.timeSeriesAxes,abs(ifft2(app.kSpaceTimeSeries(:,:,app.TimePointSpinner.Value))))
                elseif voxelData == 2 % phase
                    imagesc(app.timeSeriesAxes,angle(ifft2(fftshift(app.kSpaceTimeSeries(:,:,app.TimePointSpinner.Value)))))
                elseif voxelData == 3 % real
                    imagesc(app.timeSeriesAxes,real(ifft2(app.kSpaceTimeSeries(:,:,app.TimePointSpinner.Value))))
                elseif voxelData == 4 % imaginary
                    imagesc(app.timeSeriesAxes,imag(ifft2(app.kSpaceTimeSeries(:,:,app.TimePointSpinner.Value))))
                end
            end

            app.TextOutput.Value = 'changed view';
        end

        % Value changed function: NumberofCoilsSpinner
        function NumberofCoilsSpinnerValueChanged(app, event)
            value = app.NumberofCoilsSpinner.Value;
            app.CoilNumberSpinner.Limits = [1,value];
            app.CoilNumberSpinner.Enable = 'on';
        end

        % Button pushed function: ExportTimeSeriesButton
        function ExportTimeSeriesButtonPushed(app, event)
            ksTS = app.kSpaceTimeSeries;
            uisave('ksTS')
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.ImportMenu = uimenu(app.UIFigure);
            app.ImportMenu.Text = 'Import';
            app.ExportMenu = uimenu(app.UIFigure);
            app.ExportMenu.Text = 'Export';
            app.GridLayout = uigridlayout(app.UIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.GridLayout4 = uigridlayout(app.LeftPanel);
            app.MapAxes = uiaxes(app.GridLayout4);
            app.MapAxes.XTick = [];
            app.MapAxes.YTick = [];
            app.ActFileNameEditField = uieditfield(app.GridLayout4, 'text');
            app.ActFileNameEditField.Editable = 'off';
            app.LoadActFileButton = uibutton(app.GridLayout4, 'push');
            app.LoadActFileButton.ButtonPushedFcn = createCallbackFcn(app, @LoadActFileButtonPushed, true);
            app.LoadActFileButton.Text = 'Load Activ. File';
            app.dBFileNameEditField = uieditfield(app.GridLayout4, 'text');
            app.dBFileNameEditField.Editable = 'off';
            app.LoaddBFileButton = uibutton(app.GridLayout4, 'push');
            app.LoaddBFileButton.ButtonPushedFcn = createCallbackFcn(app, @LoaddBFileButtonPushed, true);
            app.LoaddBFileButton.Text = 'Load dB File';
            app.T2FileNameEditField = uieditfield(app.GridLayout4, 'text');
            app.T2FileNameEditField.Editable = 'off';
            app.LoadT2FileButton = uibutton(app.GridLayout4, 'push');
            app.LoadT2FileButton.ButtonPushedFcn = createCallbackFcn(app, @LoadT2FileButtonPushed, true);
            app.LoadT2FileButton.Text = 'Load T2* File';
            app.T1FileNameEditField = uieditfield(app.GridLayout4, 'text');
            app.T1FileNameEditField.Editable = 'off';
            app.LoadT1FileButton = uibutton(app.GridLayout4, 'push');
            app.LoadT1FileButton.ButtonPushedFcn = createCallbackFcn(app, @LoadT1FileButtonPushed, true);
            app.LoadT1FileButton.Text = 'Load T1 File';
            app.M0FileNameEditField = uieditfield(app.GridLayout4, 'text');
            app.M0FileNameEditField.Editable = 'off';
            app.LoadM0FileButton = uibutton(app.GridLayout4, 'push');
            app.LoadM0FileButton.ButtonPushedFcn = createCallbackFcn(app, @LoadM0FileButtonPushed, true);
            app.LoadM0FileButton.Text = 'Load M0 File';
            app.FileSelectorandViewerLabel = uilabel(app.GridLayout4);
            app.FileSelectorandViewerLabel.Text = 'File Selector and Viewer';
            app.MapViewSelect = uibuttongroup(app.GridLayout4);
            app.MapViewSelect.SelectionChangedFcn = createCallbackFcn(app, @MapViewSelectSelectionChanged, true);
            app.viewdB = uiradiobutton(app.MapViewSelect);
            app.viewdB.Enable = 'off';
            app.viewdB.Visible = 'off';
            app.viewdB.Text = '';
            app.viewT2 = uiradiobutton(app.MapViewSelect);
            app.viewT2.Text = '';
            app.viewT1 = uiradiobutton(app.MapViewSelect);
            app.viewT1.Text = '';
            app.viewM0 = uiradiobutton(app.MapViewSelect);
            app.viewM0.Text = '';
            app.viewM0.Value = true;
            app.PlotActivationCheck = uicheckbox(app.GridLayout4);
            app.PlotActivationCheck.ValueChangedFcn = createCallbackFcn(app, @PlotActivationCheckValueChanged, true);
            app.PlotActivationCheck.Text = '';
            app.TabGroup = uitabgroup(app.GridLayout4);
            app.MRParamsTab = uitab(app.TabGroup);
            app.MRParamsTab.Title = 'MR Params';
            app.GridLayout3 = uigridlayout(app.MRParamsTab);
            app.SignalEquationDropdown = uidropdown(app.GridLayout3);
            app.SignalEquationDropdown.Items = {'Gradient Echo', 'Spin Echo', 'Inversion Recovery'};
            app.SignalEquationDropdown.ItemsData = {'1', '2', '3'};
            app.SignalEquationDropdown.ValueChangedFcn = createCallbackFcn(app, @SignalEquationDropdownValueChanged, true);
            app.SignalEquationDropdown.Value = '1';
            app.ViewLabel_2 = uilabel(app.GridLayout3);
            app.ViewLabel_2.Text = 'Signal Equation';
            app.TrajectoryDropDown = uidropdown(app.GridLayout3);
            app.TrajectoryDropDown.Items = {'Cartesian', 'Radial'};
            app.TrajectoryDropDown.ItemsData = [1 2];
            app.TrajectoryDropDown.Value = 1;
            app.TrajectoryDropDownLabel = uilabel(app.GridLayout3);
            app.TrajectoryDropDownLabel.Text = 'Trajectory';
            app.AccelerationFactorSpinner = uispinner(app.GridLayout3);
            app.AccelerationFactorSpinner.Limits = [1 8];
            app.AccelerationFactorSpinner.Tooltip = {'Rate at which kspace data is subsampled'};
            app.AccelerationFactorSpinner.Value = 1;
            app.AccelerationFactorLabel = uilabel(app.GridLayout3);
            app.AccelerationFactorLabel.Text = 'Acceleration Factor';
            app.FieldStrengthTEditField = uieditfield(app.GridLayout3, 'numeric');
            app.FieldStrengthTEditField.Limits = [0 Inf];
            app.FieldStrengthTEditField.ValueChangedFcn = createCallbackFcn(app, @FieldStrengthTEditFieldValueChanged, true);
            app.FieldStrengthTEditField.Value = 3;
            app.FieldStrengthTEditFieldLabel = uilabel(app.GridLayout3);
            app.FieldStrengthTEditFieldLabel.Text = 'Field Strength (T)';
            app.EchoTimeTEmsEditField = uieditfield(app.GridLayout3, 'numeric');
            app.EchoTimeTEmsEditField.Limits = [0 Inf];
            app.EchoTimeTEmsEditField.ValueChangedFcn = createCallbackFcn(app, @EchoTimeTEmsEditFieldValueChanged, true);
            app.EchoTimeTEmsEditField.Value = 50;
            app.EchoTimeTEmsEditFieldLabel = uilabel(app.GridLayout3);
            app.EchoTimeTEmsEditFieldLabel.Text = 'Echo Time (TE, ms)';
            app.RepetitionTimeTRmsEditFieldLabel = uilabel(app.GridLayout3);
            app.RepetitionTimeTRmsEditFieldLabel.Text = 'Repetition Time (TR, ms)';
            app.RepetitionTimeTRmsEditField = uieditfield(app.GridLayout3, 'numeric');
            app.RepetitionTimeTRmsEditField.Limits = [0 Inf];
            app.RepetitionTimeTRmsEditField.ValueChangedFcn = createCallbackFcn(app, @RepetitionTimeTRmsEditFieldValueChanged, true);
            app.RepetitionTimeTRmsEditField.Value = 1000;
            app.FlipAngledegEditField = uieditfield(app.GridLayout3, 'numeric');
            app.FlipAngledegEditField.Limits = [0 Inf];
            app.FlipAngledegEditField.Value = 90;
            app.FlipAngledegEditFieldLabel = uilabel(app.GridLayout3);
            app.FlipAngledegEditFieldLabel.Text = 'Flip Angle (deg)';
            app.NumberofCoilsSpinnerLabel = uilabel(app.GridLayout3);
            app.NumberofCoilsSpinnerLabel.Text = 'Number of Coils';
            app.NumberofCoilsSpinner = uispinner(app.GridLayout3);
            app.NumberofCoilsSpinner.Limits = [1 Inf];
            app.NumberofCoilsSpinner.RoundFractionalValues = 'on';
            app.NumberofCoilsSpinner.ValueChangedFcn = createCallbackFcn(app, @NumberofCoilsSpinnerValueChanged, true);
            app.NumberofCoilsSpinner.Value = 1;
            app.fMRIParamsTab = uitab(app.TabGroup);
            app.fMRIParamsTab.Title = 'fMRI Params';
            app.GridLayout2 = uigridlayout(app.fMRIParamsTab);
            app.TaskSIgnalIncreaseLabel = uilabel(app.GridLayout2);
            app.TaskSIgnalIncreaseLabel.Text = 'Task SIgnal Increase';
            app.ActivationLabel = uilabel(app.GridLayout2);
            app.ActivationLabel.Text = 'Magnitude';
            app.ActivationMag = uieditfield(app.GridLayout2, 'numeric');
            app.ActivationMag.Tooltip = {'Given as percentage of the mximum voxel value in the non-task image'};
            app.ActivationMag.Value = 0.02;
            app.PhaseIncreaseLabel = uilabel(app.GridLayout2);
            app.PhaseIncreaseLabel.Text = 'Phase (deg)';
            app.ActivationPhase = uieditfield(app.GridLayout2, 'numeric');
            app.ActivationPhase.Tooltip = {'Angular increase of the phase in the activated ROI'};
            app.CNREditFieldLabel = uilabel(app.GridLayout2);
            app.CNREditFieldLabel.Text = 'CNR';
            app.CNREditField = uieditfield(app.GridLayout2, 'numeric');
            app.CNREditField.Limits = [0 Inf];
            app.CNREditField.Tooltip = {'Contrast-to-Noise Ratio, Beta1/sigma'};
            app.CNREditField.Value = 0.75;
            app.SliceSpinner = uispinner(app.GridLayout4);
            app.SliceSpinner.Limits = [1 96];
            app.SliceSpinner.RoundFractionalValues = 'on';
            app.SliceSpinner.ValueChangedFcn = createCallbackFcn(app, @SliceSpinnerValueChanged, true);
            app.SliceSpinner.Value = 48;
            app.SliceLabel = uilabel(app.GridLayout4);
            app.SliceLabel.Text = 'Slice';
            app.SliceViewDropDown = uidropdown(app.GridLayout4);
            app.SliceViewDropDown.Items = {'Axial', 'Sagital', 'Coronal'};
            app.SliceViewDropDown.ItemsData = {'1', '2', '3'};
            app.SliceViewDropDown.ValueChangedFcn = createCallbackFcn(app, @SliceViewDropDownValueChanged, true);
            app.SliceViewDropDown.Value = '1';
            app.ViewLabel = uilabel(app.GridLayout4);
            app.ViewLabel.Text = 'View';
            app.CenterPanel = uipanel(app.GridLayout);
            app.GridLayout5 = uigridlayout(app.CenterPanel);
            app.kSpaceAxes = uiaxes(app.GridLayout5);
            title(app.kSpaceAxes, 'k-Space')
            app.kSpaceAxes.XTick = [];
            app.kSpaceAxes.YTick = [];
            colormap(app.kSpaceAxes, 'gray')
            app.kSpaceAxes_2 = uiaxes(app.GridLayout5);
            title(app.kSpaceAxes_2, 'k-Space')
            app.kSpaceAxes_2.XTick = [];
            app.kSpaceAxes_2.YTick = [];
            colormap(app.kSpaceAxes_2, 'gray')
            app.reconAxes = uiaxes(app.GridLayout5);
            title(app.reconAxes, 'Sample Reconstruction')
            app.reconAxes.XTick = [];
            app.reconAxes.YTick = [];
            colormap(app.reconAxes, 'gray')
            app.reconAxes_2 = uiaxes(app.GridLayout5);
            title(app.reconAxes_2, 'Sample Reconstruction')
            app.reconAxes_2.XTick = [];
            app.reconAxes_2.YTick = [];
            colormap(app.reconAxes_2, 'gray')
            app.timeSeriesAxes = uiaxes(app.GridLayout5);
            title(app.timeSeriesAxes, 'Time Series')
            app.timeSeriesAxes.XTick = [];
            app.timeSeriesAxes.YTick = [];
            app.timeSeriesAxes.ButtonDownFcn = createCallbackFcn(app, @timeSeriesAxesButtonDown, true);
            colormap(app.timeSeriesAxes, 'gray')
            app.TaskDesignPanel = uipanel(app.GridLayout5);
            app.TaskDesignPanel.Title = 'Task Design';
            app.GridLayout6 = uigridlayout(app.TaskDesignPanel);
            app.HRFDropDownLabel = uilabel(app.GridLayout6);
            app.HRFDropDownLabel.Text = 'HRF';
            app.HRFDropDown = uidropdown(app.GridLayout6);
            app.HRFDropDown.Items = {'Block'};
            app.HRFDropDown.ValueChangedFcn = createCallbackFcn(app, @EchoTimeTEmsEditFieldValueChanged, true);
            app.HRFDropDown.Tooltip = {'Haemodynamic Response Function'};
            app.HRFDropDown.Value = 'Block';
            app.EpochsEditFieldLabel = uilabel(app.GridLayout6);
            app.EpochsEditFieldLabel.Text = 'Epochs';
            app.EpochsEditField = uieditfield(app.GridLayout6, 'numeric');
            app.EpochsEditField.Limits = [1 Inf];
            app.EpochsEditField.RoundFractionalValues = 'on';
            app.EpochsEditField.Tooltip = {'Repititions of task activation'};
            app.EpochsEditField.Value = 1;
            app.TaskImagesEditFieldLabel = uilabel(app.GridLayout6);
            app.TaskImagesEditFieldLabel.Text = 'Task Images';
            app.TaskImagesEditField = uieditfield(app.GridLayout6, 'numeric');
            app.TaskImagesEditField.Limits = [0 Inf];
            app.TaskImagesEditField.RoundFractionalValues = 'on';
            app.TaskImagesEditField.Tooltip = {'per epoch'};
            app.TaskImagesEditField.Value = 1;
            app.RestImagesEditFieldLabel = uilabel(app.GridLayout6);
            app.RestImagesEditFieldLabel.Text = 'Rest Images';
            app.RestImagesEditField = uieditfield(app.GridLayout6, 'numeric');
            app.RestImagesEditField.Limits = [0 Inf];
            app.RestImagesEditField.RoundFractionalValues = 'on';
            app.RestImagesEditField.Tooltip = {'per epoch'};
            app.RestImagesEditField.Value = 1;
            app.PlotDesignButton = uibutton(app.GridLayout6, 'push');
            app.PlotDesignButton.ButtonPushedFcn = createCallbackFcn(app, @PlotDesignButtonPushed, true);
            app.PlotDesignButton.Text = 'Plot Design';
            app.GenerateTimeSeriesDataButton = uibutton(app.GridLayout6, 'push');
            app.GenerateTimeSeriesDataButton.ButtonPushedFcn = createCallbackFcn(app, @GenerateTimeSeriesDataButtonPushed, true);
            app.GenerateTimeSeriesDataButton.Text = 'Generate Time Series Data';
            app.VoxelDataDropDownLabel = uilabel(app.GridLayout5);
            app.VoxelDataDropDownLabel.Text = 'Voxel Data';
            app.VoxelDataDropDown = uidropdown(app.GridLayout5);
            app.VoxelDataDropDown.Items = {'Magnitude', 'Phase', 'Real', 'Imaginary'};
            app.VoxelDataDropDown.ItemsData = [1 2 3 4];
            app.VoxelDataDropDown.ValueChangedFcn = createCallbackFcn(app, @timeSeriesAxesButtonDown, true);
            app.VoxelDataDropDown.Value = 1;
            app.kSpaceButton = uibutton(app.GridLayout5, 'push');
            app.kSpaceButton.ButtonPushedFcn = createCallbackFcn(app, @kSpaceButtonPushed, true);
            app.kSpaceButton.Text = 'Generate Single kSpace';
            app.TimePointSpinnerLabel = uilabel(app.GridLayout5);
            app.TimePointSpinnerLabel.Text = 'Time Point';
            app.TimePointSpinner = uispinner(app.GridLayout5);
            app.TimePointSpinner.Limits = [1 Inf];
            app.TimePointSpinner.RoundFractionalValues = 'on';
            app.TimePointSpinner.ValueChangedFcn = createCallbackFcn(app, @timeSeriesAxesButtonDown, true);
            app.TimePointSpinner.Enable = 'off';
            app.TimePointSpinner.Value = 1;
            app.DomainDropDownLabel = uilabel(app.GridLayout5);
            app.DomainDropDownLabel.Text = 'Domain';
            app.DomainDropDown = uidropdown(app.GridLayout5);
            app.DomainDropDown.Items = {'kSpace', 'Image Space'};
            app.DomainDropDown.ItemsData = [1 2];
            app.DomainDropDown.DropDownOpeningFcn = createCallbackFcn(app, @timeSeriesAxesButtonDown, true);
            app.DomainDropDown.ValueChangedFcn = createCallbackFcn(app, @timeSeriesAxesButtonDown, true);
            app.DomainDropDown.ClickedFcn = createCallbackFcn(app, @timeSeriesAxesButtonDown, true);
            app.DomainDropDown.Value = 1;
            app.CoilNumberSpinnerLabel = uilabel(app.GridLayout5);
            app.CoilNumberSpinnerLabel.Text = 'Coil Number';
            app.CoilNumberSpinner = uispinner(app.GridLayout5);
            app.CoilNumberSpinner.Limits = [1 Inf];
            app.CoilNumberSpinner.RoundFractionalValues = 'on';
            app.CoilNumberSpinner.Enable = 'off';
            app.CoilNumberSpinner.Value = 1;
            app.ActivationLamp = uilamp(app.GridLayout5);
            app.ActivationLamp.Tooltip = {'Red if in rest state, grreen if in task state'};
            app.ActivationLabel_2 = uilabel(app.GridLayout5);
            app.ActivationLabel_2.Text = 'Activation';
            app.RightPanel = uipanel(app.GridLayout);
            app.GridLayout7 = uigridlayout(app.RightPanel);
            app.reconstructButton = uibutton(app.GridLayout7, 'push');
            app.reconstructButton.ButtonPushedFcn = createCallbackFcn(app, @reconstructButtonPushed, true);
            app.reconstructButton.Enable = 'off';
            app.reconstructButton.Visible = 'off';
            app.reconstructButton.Text = 'Reconstruct';
            app.Hyperlink = uihyperlink(app.GridLayout7);
            app.Hyperlink.URL = 'https://github.com/bodensjc/kspace-simulator';
            app.Hyperlink.Text = 'GitHub';
            app.kSpaceSimulationToolMarquetteUniversityLabel = uilabel(app.GridLayout7);
            app.kSpaceSimulationToolMarquetteUniversityLabel.Text = {'k-Space Simulation Tool'; 'Marquette University'};
            app.ExportTimeSeriesButton = uibutton(app.GridLayout7, 'push');
            app.ExportTimeSeriesButton.ButtonPushedFcn = createCallbackFcn(app, @ExportTimeSeriesButtonPushed, true);
            app.ExportTimeSeriesButton.Text = 'Export Time Series';
            app.ExportSinglekSpaceButton = uibutton(app.GridLayout7, 'state');
            app.ExportSinglekSpaceButton.ValueChangedFcn = createCallbackFcn(app, @ExportSinglekSpaceButtonValueChanged, true);
            app.ExportSinglekSpaceButton.Text = 'Export Single kSpace';
            app.TextOutput = uitextarea(app.GridLayout7);
            app.TextOutput.Editable = 'off';
            app.TextOutput.Value = {'kspace  simulator'};
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = ksim

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end